classdef Tonghop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Nghiem                         matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ButtonChiaDoi                  matlab.ui.control.Button
        ButtonLap                      matlab.ui.control.Button
        ButtonTiepTuyen                matlab.ui.control.Button
        ButtonDayCung                  matlab.ui.control.Button
        LabelPhanLi                    matlab.ui.control.Label
        LabelPhuongPhap                matlab.ui.control.Label
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        EditFieldPT                    matlab.ui.control.EditField
        aEditFieldLabel_2              matlab.ui.control.Label
        EditFielda                     matlab.ui.control.EditField
        bEditFieldLabel_2              matlab.ui.control.Label
        EditFieldb                     matlab.ui.control.EditField
        NhpsaischophpEditFieldLabel_2  matlab.ui.control.Label
        EditFielde                     matlab.ui.control.EditField
        NghimEditFieldLabel_2          matlab.ui.control.Label
        EditFieldNghiem                matlab.ui.control.EditField
        SlnlpEditFieldLabel_2          matlab.ui.control.Label
        EditFieldSoLanLap              matlab.ui.control.EditField
        NoiSuy                         matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        EditFieldDaThucNoiSuy          matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        EditFieldXa                    matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        EditFieldYa                    matlab.ui.control.EditField
        NhpgitrcnnisuyLabel_2          matlab.ui.control.Label
        EditFieldGiaTriNoiSuy          matlab.ui.control.EditField
        KtqunisuyEditFieldLabel_2      matlab.ui.control.Label
        EditFieldKetQua                matlab.ui.control.EditField
        LagrangeButton                 matlab.ui.control.Button
        NewtonButton                   matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        HoiQuy                         matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        Label3                         matlab.ui.control.Label
        KtquphngtrnhhiquyLabel         matlab.ui.control.Label
        EditFieldDaThucHoiQuy          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2       matlab.ui.control.Label
        EditFieldXi                    matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2       matlab.ui.control.Label
        EditFieldYi                    matlab.ui.control.EditField
        ButtonTuyenTinh                matlab.ui.control.Button
        NhpgitrcndonEditFieldLabel     matlab.ui.control.Label
        EditFieldGiaTriDuDoan          matlab.ui.control.EditField
        KtqudonEditFieldLabel          matlab.ui.control.Label
        EditFieldKetQuaDuDoan          matlab.ui.control.EditField
        ButtonMu                       matlab.ui.control.Button
        DaoHam                         matlab.ui.container.Tab
        NhpdliuxEditFieldLabel_3       matlab.ui.control.Label
        EditFieldX                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3       matlab.ui.control.Label
        EditFieldY                     matlab.ui.control.EditField
        Label4                         matlab.ui.control.Label
        NhphmsEditFieldLabel           matlab.ui.control.Label
        EditFieldFunc                  matlab.ui.control.EditField
        Label6                         matlab.ui.control.Label
        ButtonMid                      matlab.ui.control.Button
        ButtonBack                     matlab.ui.control.Button
        EditFieldForward               matlab.ui.control.Button
        KtquEditFieldLabel             matlab.ui.control.Label
        EditFieldKetQuaDaoHam          matlab.ui.control.EditField
        GitrsaisSwitchLabel            matlab.ui.control.Label
        SwitchGiatriSaiso              matlab.ui.control.Switch
        NhpbchEditFieldLabel_2         matlab.ui.control.Label
        EditFieldH                     matlab.ui.control.EditField
        NhpgitrcntnhohmEditFieldLabel_2  matlab.ui.control.Label
        EditFieldGiaTriDaoHam          matlab.ui.control.EditField
        TichPhan                       matlab.ui.container.Tab
        NhpdliuxEditFieldLabel_4       matlab.ui.control.Label
        EditFieldXT                    matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_4       matlab.ui.control.Label
        EditFieldYT                    matlab.ui.control.EditField
        Label7                         matlab.ui.control.Label
        NhphmsEditFieldLabel_2         matlab.ui.control.Label
        EditFieldFuncT                 matlab.ui.control.EditField
        NhpcntnhtchphnEditFieldLabel   matlab.ui.control.Label
        EditFieldNhapCan               matlab.ui.control.EditField
        KtquEditFieldLabel_2           matlab.ui.control.Label
        EditFieldKetQuaTichPhan        matlab.ui.control.EditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        EditFieldNhapN                 matlab.ui.control.EditField
        ButtonSimpson13                matlab.ui.control.Button
        ButtonSimpson38                matlab.ui.control.Button
        ButtonHinhThang                matlab.ui.control.Button
        Label8                         matlab.ui.control.Label
        GioiThieuNhom                  matlab.ui.container.Tab
        SinhvinthchinTextAreaLabel     matlab.ui.control.Label
        SinhvinthchinTextArea          matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditFieldPT
        function EditFieldPTValueChanged(app, event)
            global phuongtrinh;
            phuongtrinh = app.EditFieldPT.Value;
            phuongtrinh = str2func(['@(x)',phuongtrinh]);
        end

        % Value changed function: EditFielda
        function EditFieldaValueChanged(app, event)
            global a;
            a = app.EditFielda.Value;
            a = str2double(a);
        end

        % Value changed function: EditFieldb
        function EditFieldbValueChanged(app, event)
            global b;
            b = app.EditFieldb.Value;
            b = str2double(b);
        end

        % Value changed function: EditFielde
        function EditFieldeValueChanged(app, event)
            global e;
            e = app.EditFielde.Value;
            e = str2double(e);
        end

        % Button pushed function: ButtonChiaDoi
        function ButtonChiaDoiPushed(app, event)
            global a;
            global phuongtrinh;
            global e;
            global b;
%----------------------------------------------------------------
            x = linspace(a,b,100);
            y = phuongtrinh(x);
            A = a; B = b;
            solanlap = 0;
            nghiem = 0;
            while abs(B-A) >= e
                nghiem = (A+B)/2;       %chon nghiem nam giua khoang phan li
                if phuongtrinh(nghiem)*phuongtrinh(A) > 0   %xet nghiem ton tai giua a va nghiem
                    A = nghiem;         %vi nghiem ko ton tai trong [a;nghiem] 
                else
                    B = nghiem;         %vi nghiem ton tai trong [a;b]
                end
                solanlap = solanlap + 1;
            end
%---------------------------------------------------------------- 
            figure('Visible',"off");
            plot(app.UIAxes,x,y,nghiem,phuongtrinh(nghiem),'r*');
            app.EditFieldNghiem.Value = sprintf('%.4f',nghiem);
            app.EditFieldSoLanLap.Value = sprintf('%d',solanlap);
        end

        % Button pushed function: ButtonTiepTuyen
        function ButtonTiepTuyenPushed(app, event)
            global a;
            global phuongtrinh;
            global e;
            global b;
%----------------------------------------------------------------
            syms V;
            fd1 = str2func(['@(V)' char(diff(phuongtrinh(V)))]);
            x = linspace(a,b,100);
            y = phuongtrinh(x);
            nghiem = (a+b)/2;
            solanlap = 0;
            while(true)
                z = phuongtrinh(nghiem)/fd1(nghiem);
                s = nghiem - z;
                if abs(s - nghiem) < e
                    break;
                else
                    nghiem = s;
                    solanlap = solanlap + 1;
                end
            end
%----------------------------------------------------------------
            figure('Visible',"off");
            plot(app.UIAxes,x,y,nghiem,phuongtrinh(nghiem),'r*');
            app.EditFieldNghiem.Value = sprintf('%.4f',nghiem);
            app.EditFieldSoLanLap.Value = sprintf('%d',solanlap);
        end

        % Button pushed function: ButtonDayCung
        function ButtonDayCungPushed(app, event)
            global a;
            global phuongtrinh;
            global e;
            global b;
%----------------------------------------------------------------
            x = linspace(a,b,100);
            y = phuongtrinh(x);
            A = a; B = b;
            solanlap = 0;
            while (true)
                nghiem = (A*phuongtrinh(B)-B*phuongtrinh(A))/(phuongtrinh(B)-phuongtrinh(A));
                if solanlap >= 1
                    if  abs(nghiem - c) < e        %|x_n - x_{n-1} < saiso|
                        break;     
                    end
                end
                %thuat toan giong phuong phap chia doi
                if phuongtrinh(nghiem)*phuongtrinh(A) < 0
                    B = nghiem;
                else
                    A = nghiem;
                end
                c = nghiem;                             %gan bien tam c = x_n
                solanlap = solanlap + 1;
            end
%----------------------------------------------------------------
            figure('Visible',"off");
            plot(app.UIAxes,x,y,nghiem,phuongtrinh(nghiem),'r*');
            app.EditFieldNghiem.Value = sprintf('%.4f',nghiem);
            app.EditFieldSoLanLap.Value = sprintf('%d',solanlap);          
        end

        % Button pushed function: ButtonLap
        function ButtonLapPushed(app, event)
            global a;
            global phuongtrinh;
            global e;
            global b;
%----------------------------------------------------------------
            x = linspace(a,b,100);
            y = phuongtrinh(x);
            %t = x;
            A = a; B = b;
            solanlap = 0;
            nghiem = (A + B)/2;                 %chon nghiem nam giua [a;b]
            while (true)
                z = phuongtrinh(nghiem);                 % x_n = g(x_{n-1})
                if abs(z - nghiem) < e      %|x_n - g(x_{n-1})| < saiso
                    break;
                else
                    nghiem = z;                 %gan x_{n-1} = x_n cho vong lap tiep theo
                    solanlap = solanlap + 1;
                end
            end
%-----------------------------------------------------------------
            figure('Visible',"off");
            plot(app.UIAxes,x,y,x,x,nghiem,phuongtrinh(nghiem),'r*');
            app.EditFieldNghiem.Value = sprintf('%.4f',nghiem);
            app.EditFieldSoLanLap.Value = sprintf('%d',solanlap);
        end

        % Value changing function: EditFieldGiaTriNoiSuy
        function EditFieldGiaTriNoiSuyValueChanged(app, event)
            global x0;
            x0 = app.EditFieldGiaTriNoiSuy.Value;
            x0 = str2double(x0);
        end

        % Button pushed function: LagrangeButton
        function LagrangeButtonValueChanged(app, event)
            global xa;
            global ya;
            global x0;
%------------------------------------------------------------------
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product * (x0 - xa(j)) / (xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            app.EditFieldKetQua.Value = sprintf('%.4f',sum);
% %-------------------------------------------------------------------
            syms x;
            P = 0;
            for k = 1:n
                L_k = 1;
                for i = 1:n
                    if i ~= k
                        L_k = L_k * (x - xa(i)) / (xa(k) - xa(i));
                    end
                end
                P = P + ya(k) * L_k;
            end
            P = simplify(P); % Rut gon da thuc
            Pstr = char(P);
            app.EditFieldDaThucNoiSuy.Value = Pstr;
%--------------------------------------------------------------------
            t = linspace(xa(1),xa(end),100);
            f = matlabFunction(P);
            figure('Visible',"off");
            plot(app.UIAxes2,t,f(t),x0,f(x0),'r*');
        end

        % Button pushed function: NewtonButton
        function NewtonButtonValueChanged(app, event)
            global xa;
            global ya;
            global x0;
            global ForwardOrBack;
%--------------------------------------------------------------------
            %function d = DividedDifference(xa,ya)
            n = length(xa);
            d = ya;
            h = xa;
            if ForwardOrBack == "Back"
                d = fliplr(d);
                h = fliplr(h);
            end
            for i = 1:n
                for j = 1:i-1
                    d(i) = (d(j) - d(i)) / (h(j) - h(i));
                end
            end
            %function result = NewtonForm(xa, da, x)
            n = length(d);
            result = d(n);
            for i = n-1:-1:1
                result = result * (x0 - h(i)) + d(i);
            end
            %end
            app.EditFieldKetQua.Value = sprintf('%.4f',result);
%--------------------------------------------------------------------
            syms x;
            d = ya;
            h = xa;
            if ForwardOrBack == "Back"
                d = fliplr(d);
                h = fliplr(h);
            end
            P = d(1);        
            % Tinh bang hieu chia
            divided_differences = zeros(n, n);
            divided_differences(:,1) = d';
            for j = 2:n
                for i = j:n
                    divided_differences(i,j) = (divided_differences(i,j-1) - divided_differences(i-1,j-1)) / (h(i) - h(i-j+1));
                end
            end
            % Tinh da thuc noi suy
            for i = 1:n-1
                term = 1;
                for j = 1:i
                    term = term * (x - h(j));
                end
                P = P + divided_differences(i+1, i+1) * term;
            end
            P = simplify(P);%Rut gon da thuc
            Pstr = char(P);
            app.EditFieldDaThucNoiSuy.Value = Pstr;
%--------------------------------------------------------------------
            t = linspace(xa(1),xa(end),100);
            f = matlabFunction(P);
            figure('Visible',"off");
            plot(app.UIAxes2,t,f(t),x0,f(x0),'r*');
        end

        % Value changed function: EditFieldXa
        function EditFieldXaValueChanged(app, event)
            global xa;
            temp = strsplit(app.EditFieldXa.Value,' ');
            xa = zeros(size(temp));
            for i = 1:length(temp)
                xa(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldYa
        function EditFieldYaValueChanged(app, event)
            global ya;
            temp = strsplit(app.EditFieldYa.Value,' ');
            ya = zeros(size(temp));
            for i = 1:length(temp)
                ya(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldXi
        function EditFieldXiValueChanged(app, event)
            global xi;
            temp = strsplit(app.EditFieldXi.Value,' ');
            xi = zeros(size(temp));
            for i = 1:length(temp)
                xi(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldYi
        function EditFieldYiValueChanged(app, event)
            global yi;
            temp = strsplit(app.EditFieldYi.Value,' ');
            yi = zeros(size(temp));
            for i = 1:length(temp)
                yi(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldGiaTriDuDoan
        function EditFieldGiaTriDuDoanValueChanged(app, event)
            global xe;
            xe = app.EditFieldGiaTriDuDoan.Value;
            xe = str2double(xe);
        end

        % Button pushed function: ButtonTuyenTinh
        function ButtonTuyenTinhPushed(app, event)
            global xi;
            global yi;
            global xe;
%--------------------------------------------------------------------  
%        function tuyentinh(xi,yi,x0)
            z = [sum(xi.^2) sum(xi); sum(xi) length(xi)];
            y = [sum(xi.*yi); sum(yi)]; 
            a = z\y; a = a(1,1);
            b = z\y; b = b(2,1);
            syms x;
            f = @(x) a*x + b;
%--------------------------------------------------------------------
            figure('Visible', "off");
            t = linspace(xi(1),xi(end),100);
            app.UIAxes.Visible = true;
            plot(app.UIAxes3,t,f(t),xi,yi,'r*');
%--------------------------------------------------------------------
            % Construct the string manually
            str_f = sprintf('y = %.4f*x + %.4f', a, b);
            % Assign the string to the Edit Field
            app.EditFieldDaThucHoiQuy.Value = str_f;
%--------------------------------------------------------------------
            app.EditFieldKetQuaDuDoan.Value = sprintf('%.4f',f(xe)); 
        end

        % Button pushed function: ButtonMu
        function ButtonMuValueChanged(app, event)
            global xi;
            global yi;
            global xe;
%--------------------------------------------------------------------  
%        function mu(xi,yi,x0)
            z = [sum(xi.^2) sum(xi); sum(xi) length(xi)];
            y = [sum(xi.*log10(yi)); sum(log10(yi))]; 
            A = z\y; A = A(1,1);
            b = A/log10(exp(1));
            B = z\y; B = B(2,1);
            a = 10^B;
            syms x;
            f = @(x) a*exp(b*x);
%--------------------------------------------------------------------
            figure('Visible', 'off');
            t = linspace(xi(1),xi(end),100);
            app.UIAxes.Visible = true;
            plot(app.UIAxes3,t,f(t),xi,yi,'r*');
%--------------------------------------------------------------------
            % Construct the string manually
            str_f = sprintf('y = %.4f*e^(%.4fx)', a, b);
            % Assign the string to the Edit Field
            app.EditFieldDaThucHoiQuy.Value = str_f;
%--------------------------------------------------------------------
            app.EditFieldKetQuaDuDoan.Value = sprintf('%.4f',f(xe));    
        end

        % Value changed function: EditFieldGiaTriNoiSuy, EditFieldX
        function EditFieldXValueChanged(app, event)
            global X;
            temp = strsplit(app.EditFieldX.Value,' ');
            X = zeros(size(temp));
            for i = 1:length(temp)
                X(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldY
        function EditFieldYValueChanged(app, event)
            global Y;
            temp = strsplit(app.EditFieldY.Value,' ');
            Y = zeros(size(temp));
            for i = 1:length(temp)
                Y(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldFunc
        function EditFieldFuncValueChanged(app, event)
            global f;
            f = app.EditFieldFunc.Value;
            f = str2func(['@(x)',f]);
        end

        % Button pushed function: EditFieldForward
        function EditFieldForwardButtonPushed(app, event)
            global X;
            global Y;
            global f;
            global H;
            global GiatriSaiso;
            global Giatridaoham;
%-------------------------------------------------------------------- 
            if ~isempty(app.EditFieldX.Value) && ~isempty(app.EditFieldY.Value) 
                % Nếu nhập x và y 
                index = find(X == Giatridaoham);
                if isempty(index)
                    app.EditFieldKetQuaDaoHam.Value = 'Error!';
                end
                h = X(index+1) - X(index);
                if (GiatriSaiso == "O(h) is chosen")
                    if X(index) <= X(end - 1) 
                        approx_derivative = (Y(index+1) - Y(index)) / h;
                        app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                    else
                        app.EditFieldKetQuaDaoHam.Value = 'Error!';
                    end
                elseif (GiatriSaiso == "O(h^2) is chosen")
                        if X(index) <= X(end - 2)
                            approx_derivative = (- Y(index+2) + 4*Y(index+1) - 3*Y(index)) / (2*h);
                            app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                        else 
                            app.EditFieldKetQuaDaoHam.Value = 'Error!';
                        end
                end
%-------------------------------------------------------------------- 
            elseif ~isempty(app.EditFieldFunc.Value)
                % Nếu có nhập hàm số
                if (GiatriSaiso == "O(h) is chosen")
                    approx_derivative = (f(Giatridaoham+H) - f(Giatridaoham)) / H;
                elseif (GiatriSaiso == "O(h^2) is chosen")
                    approx_derivative = (- f(Giatridaoham+2*H) + 4*f(Giatridaoham+H) - 3*f(Giatridaoham)) / (2*H);
                end 
                app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
            end      

        end

        % Value changed function: SwitchGiatriSaiso
        function SwitchGiatriSaisoValueChanged(app, event)
            global GiatriSaiso;
            GiatriSaiso = app.SwitchGiatriSaiso.Value;
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            global X;
            global Y;
            global f;
            global H;
            global GiatriSaiso;
            global Giatridaoham;
%-------------------------------------------------------------------- 
            if ~isempty(app.EditFieldX.Value) && ~isempty(app.EditFieldY.Value) 
                % Nếu nhập x và y 
                index = find(X == Giatridaoham);
                if isempty(index)
                    app.EditFieldKetQuaDaoHam.Value = 'Error!';
                end
                h = X(index) - X(index-1);
                if (GiatriSaiso == "O(h) is chosen")
                        if X(index) >= X(2) 
                            approx_derivative = (Y(index) - Y(index-1)) / h;
                            app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                        else
                            app.EditFieldKetQuaDaoHam.Value = 'Error!';
                        end
                elseif (GiatriSaiso == "O(h^2) is chosen")
                        if X(index) >= X(3)
                            approx_derivative = (3*Y(index) - 4*Y(index-1) + Y(index-2)) / (2*h);
                            app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                        else 
                            app.EditFieldKetQuaDaoHam.Value = 'Error!';
                        end
                end
%-------------------------------------------------------------------- 
            elseif ~isempty(app.EditFieldFunc.Value)
                % Nếu có nhập hàm số
                if (GiatriSaiso == "O(h) is chosen")
                    approx_derivative = (f(Giatridaoham) - f(Giatridaoham-H)) / H;
                elseif (GiatriSaiso == "O(h^2) is chosen")
                    approx_derivative = (3*f(Giatridaoham) - 4*f(Giatridaoham-H) + f(Giatridaoham-2*H)) / (2*H);
                end
                app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
            end
%--------------------------------------------------------------------        
        end

        % Button pushed function: ButtonMid
        function ButtonMidPushed(app, event)
            global X;
            global Y;
            global f;
            global H;
            global GiatriSaiso;
            global Giatridaoham;
%-------------------------------------------------------------------- 
            if ~isempty(app.EditFieldX.Value) && ~isempty(app.EditFieldY.Value) 
                % Nếu nhập x và y 
                index = find(X == Giatridaoham);
                if isempty(index)
                    app.EditFieldKetQuaDaoHam.Value = 'Error!';
                end
                h = X(index) - X(index-1);
                if (GiatriSaiso == "O(h) is chosen")
                        if X(index) > X(1) && X(index) < X(end) 
                            approx_derivative = (Y(index+1) - Y(index-1)) / (2*h);
                            app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                        else
                            app.EditFieldKetQuaDaoHam.Value = 'Error!';
                        end
                elseif (GiatriSaiso == "O(h^2) is chosen")
                        if X(index) > X(2) && X(index) < X(end-1)
                            approx_derivative = (-Y(index+2) + 8*Y(index+1) - 8*Y(index-1) + Y(index-2)) / (12*h);
                            app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                        else 
                            app.EditFieldKetQuaDaoHam.Value = 'Error!';
                        end
                end
%-------------------------------------------------------------------- 
            elseif ~isempty(app.EditFieldFunc.Value)
                % Nếu có nhập hàm số
                if (GiatriSaiso == "O(h) is chosen")
                    approx_derivative = (f(Giatridaoham+H) - f(Giatridaoham-H)) / (2*H);
                    app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                elseif (GiatriSaiso == "O(h^2) is chosen")
                    approx_derivative = (-f(Giatridaoham+2*H) + 8*f(Giatridaoham+H) - 8*f(Giatridaoham-H) + f(Giatridaoham-2*H)) / (12*H);
                    app.EditFieldKetQuaDaoHam.Value = sprintf('%.4f', approx_derivative);
                end        
            end
%--------------------------------------------------------------------                  
        end

        % Value changed function: EditFieldXT
        function EditFieldXTValueChanged(app, event)
            global XT;
            temp = strsplit(app.EditFieldXT.Value,' ');
            XT = zeros(size(temp));
            for i = 1:length(temp)
                XT(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldH
        function EditFieldHValueChanged(app, event)
            global H;
            H = app.EditFieldH.Value;
            H = str2double(H);
        end

        % Value changed function: EditFieldGiaTriDaoHam
        function EditFieldGiaTriDaoHamValueChanged(app, event)
            global Giatridaoham;
            Giatridaoham = app.EditFieldGiaTriDaoHam.Value;
            Giatridaoham = str2double(Giatridaoham);
        end

        % Value changed function: EditFieldYT
        function EditFieldYTValueChanged(app, event)
            global YT;
            temp = strsplit(app.EditFieldYT.Value,' ');
            YT = zeros(size(temp));
            for i = 1:length(temp)
                YT(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldFuncT
        function EditFieldFuncTValueChanged(app, event)
            global FT;
            FT = app.EditFieldFuncT.Value;
            FT = str2func(['@(x)',FT]);
        end

        % Value changed function: EditFieldNhapCan
        function EditFieldNhapCanValueChanged(app, event)
            global CanT;
            temp = strsplit(app.EditFieldNhapCan.Value,' ');
            CanT = zeros(size(temp));
            for i = 1:length(temp)
                CanT(i) = str2double(temp(i));
            end
        end

        % Value changed function: EditFieldNhapN
        function EditFieldNhapNValueChanged(app, event)
            global NT;
            NT = app.EditFieldNhapN.Value;
            NT = str2double(NT);
        end

        % Button pushed function: ButtonHinhThang
        function ButtonHinhThangPushed(app, event)
            global XT;
            global YT;
            global FT;
            global CanT;
            global NT;
%--------------------------------------------------------------------
            if ~isempty(app.EditFieldXT.Value) && ~isempty(app.EditFieldYT)
                h = (XT(end) - XT(1)) / (length(XT) - 1);
                I = h/2 * (YT(1) + YT(end) + 2*sum(YT(2:end-1)));
%-------------------------------------------------------------------- 
            elseif ~isempty(app.EditFieldFuncT.Value)
                h = (CanT(2) - CanT(1)) / NT;
                x = CanT(1):h:CanT(end);
                y = FT(x);
                I = h/2 * (y(1) + y(end) + 2*sum(y(2:end-1)));
            end
%--------------------------------------------------------------------
            app.EditFieldKetQuaTichPhan.Value = sprintf('%.4f', I);
        end

        % Button pushed function: ButtonSimpson13
        function ButtonSimpson13Pushed(app, event)
            global XT;
            global YT;
            global FT;
            global CanT;
            global NT;
%--------------------------------------------------------------------
            if ~isempty(app.EditFieldXT.Value) && ~isempty(app.EditFieldYT)
                h = (XT(end) - XT(1)) / (length(XT) - 1);
                I = h/3 * (YT(1) + YT(end) + 4*sum(YT(2:2:end-1)) + 2*sum(YT(3:2:end-1)));
%--------------------------------------------------------------------  
            elseif ~isempty(app.EditFieldFuncT.Value)
                h = (CanT(end) - CanT(1)) / NT;
                x = CanT(1):h:CanT(end);
                y = FT(x);
                I = h/3 * (y(1) + y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-1)));
            end
%--------------------------------------------------------------------
            app.EditFieldKetQuaTichPhan.Value = sprintf('%.4f', I);
        end

        % Button pushed function: ButtonSimpson38
        function ButtonSimpson38Pushed(app, event)
            global XT;
            global YT;
            global FT;
            global CanT;
            global NT;
%--------------------------------------------------------------------
            if ~isempty(app.EditFieldXT.Value) && ~isempty(app.EditFieldYT)
                if mod(length(XT)-1, 3) ~= 0
                    app.EditFieldKetQuaTichPhan.Value = "Error!";
                else
                    if (length(XT)-1) / 3 == 1
                        h = (XT(end) - XT(1)) / (length(XT) - 1);
                        I = (3*h/8) * (YT(1) + 3*YT(2) + 3*YT(3) + YT(end));
                    else
                        h = (XT(end) - XT(1)) / (length(XT) - 1);
                        I = (3*h/8) * (YT(1) + 3*sum(YT(2:3:end-1)) + 3*sum(YT(3:3:end-1)) + 2*sum(YT(4:3:end-1)) + YT(end));
                    end
                    app.EditFieldKetQuaTichPhan.Value = sprintf('%.4f', I);
                end
%--------------------------------------------------------------------
            elseif ~isempty(app.EditFieldFuncT.Value)
                if mod(NT, 3) ~= 0
                    app.EditFieldKetQuaTichPhan.Value = "Error!";
                else
                    h = (CanT(end) - CanT(1)) / NT;
                    x = CanT(1):h:CanT(end);
                    y = FT(x);
                    I = (3*h/8) * (y(1) + 3*sum(y(2:3:end-1)) + 3*sum(y(3:3:end-1)) + 2*sum(y(4:3:end-1)) + y(end));
                    app.EditFieldKetQuaTichPhan.Value = sprintf('%.4f', I);
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global ForwardOrBack;
            ForwardOrBack = app.Switch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Nghiem = uitab(app.TabGroup);
            app.Nghiem.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.Nghiem);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonChiaDoi = uibutton(app.Nghiem, 'push');
            app.ButtonChiaDoi.ButtonPushedFcn = createCallbackFcn(app, @ButtonChiaDoiPushed, true);
            app.ButtonChiaDoi.Text = 'Chia đôi';
            app.ButtonLap = uibutton(app.Nghiem, 'push');
            app.ButtonLap.ButtonPushedFcn = createCallbackFcn(app, @ButtonLapPushed, true);
            app.ButtonLap.Text = 'Lặp';
            app.ButtonTiepTuyen = uibutton(app.Nghiem, 'push');
            app.ButtonTiepTuyen.ButtonPushedFcn = createCallbackFcn(app, @ButtonTiepTuyenPushed, true);
            app.ButtonTiepTuyen.Text = 'Tiếp tuyến';
            app.ButtonDayCung = uibutton(app.Nghiem, 'push');
            app.ButtonDayCung.ButtonPushedFcn = createCallbackFcn(app, @ButtonDayCungPushed, true);
            app.ButtonDayCung.Text = 'Dây cung';
            app.LabelPhanLi = uilabel(app.Nghiem);
            app.LabelPhanLi.Text = 'Nhập khoảng phân li nghiệm';
            app.LabelPhuongPhap = uilabel(app.Nghiem);
            app.LabelPhuongPhap.Text = 'Chọn phương pháp';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.Nghiem);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.EditFieldPT = uieditfield(app.Nghiem, 'text');
            app.EditFieldPT.ValueChangedFcn = createCallbackFcn(app, @EditFieldPTValueChanged, true);
            app.aEditFieldLabel_2 = uilabel(app.Nghiem);
            app.aEditFieldLabel_2.Text = 'a';
            app.EditFielda = uieditfield(app.Nghiem, 'text');
            app.EditFielda.ValueChangedFcn = createCallbackFcn(app, @EditFieldaValueChanged, true);
            app.bEditFieldLabel_2 = uilabel(app.Nghiem);
            app.bEditFieldLabel_2.Text = 'b';
            app.EditFieldb = uieditfield(app.Nghiem, 'text');
            app.EditFieldb.ValueChangedFcn = createCallbackFcn(app, @EditFieldbValueChanged, true);
            app.NhpsaischophpEditFieldLabel_2 = uilabel(app.Nghiem);
            app.NhpsaischophpEditFieldLabel_2.Text = 'Nhập sai số cho phép';
            app.EditFielde = uieditfield(app.Nghiem, 'text');
            app.EditFielde.ValueChangedFcn = createCallbackFcn(app, @EditFieldeValueChanged, true);
            app.NghimEditFieldLabel_2 = uilabel(app.Nghiem);
            app.NghimEditFieldLabel_2.Text = 'Nghiệm';
            app.EditFieldNghiem = uieditfield(app.Nghiem, 'text');
            app.EditFieldNghiem.Editable = 'off';
            app.SlnlpEditFieldLabel_2 = uilabel(app.Nghiem);
            app.SlnlpEditFieldLabel_2.Text = 'Số lần lặp';
            app.EditFieldSoLanLap = uieditfield(app.Nghiem, 'text');
            app.EditFieldSoLanLap.Editable = 'off';
            app.NoiSuy = uitab(app.TabGroup);
            app.NoiSuy.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NoiSuy);
            title(app.UIAxes2, 'Đồ thị hàm số nội suy và dữ liệu thực ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NoiSuy);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.EditFieldDaThucNoiSuy = uieditfield(app.NoiSuy, 'text');
            app.EditFieldDaThucNoiSuy.Editable = 'off';
            app.Label = uilabel(app.NoiSuy);
            app.Label.Text = 'Phương pháp';
            app.NhpdliuxEditFieldLabel = uilabel(app.NoiSuy);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.EditFieldXa = uieditfield(app.NoiSuy, 'text');
            app.EditFieldXa.ValueChangedFcn = createCallbackFcn(app, @EditFieldXaValueChanged, true);
            app.NhpdliuyEditFieldLabel = uilabel(app.NoiSuy);
            app.NhpdliuyEditFieldLabel.Text = 'Nhâp dữ liệu y';
            app.EditFieldYa = uieditfield(app.NoiSuy, 'text');
            app.EditFieldYa.ValueChangedFcn = createCallbackFcn(app, @EditFieldYaValueChanged, true);
            app.NhpgitrcnnisuyLabel_2 = uilabel(app.NoiSuy);
            app.NhpgitrcnnisuyLabel_2.Text = 'Nhập giá trị cần nội suy';
            app.EditFieldGiaTriNoiSuy = uieditfield(app.NoiSuy, 'text');
            app.EditFieldGiaTriNoiSuy.ValueChangedFcn = createCallbackFcn(app, @EditFieldXValueChanged, true);
            app.EditFieldGiaTriNoiSuy.ValueChangingFcn = createCallbackFcn(app, @EditFieldGiaTriNoiSuyValueChanged, true);
            app.KtqunisuyEditFieldLabel_2 = uilabel(app.NoiSuy);
            app.KtqunisuyEditFieldLabel_2.Text = 'Kết quả nội suy';
            app.EditFieldKetQua = uieditfield(app.NoiSuy, 'text');
            app.EditFieldKetQua.Editable = 'off';
            app.LagrangeButton = uibutton(app.NoiSuy, 'push');
            app.LagrangeButton.ButtonPushedFcn = createCallbackFcn(app, @LagrangeButtonValueChanged, true);
            app.LagrangeButton.Text = 'Lagrange';
            app.NewtonButton = uibutton(app.NoiSuy, 'push');
            app.NewtonButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonButtonValueChanged, true);
            app.NewtonButton.Text = 'Newton';
            app.Switch = uiswitch(app.NoiSuy, 'slider');
            app.Switch.Items = {'Back', 'Forward'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Back';
            app.HoiQuy = uitab(app.TabGroup);
            app.HoiQuy.Title = 'Hồi quy';
            app.UIAxes3 = uiaxes(app.HoiQuy);
            title(app.UIAxes3, 'Đồ thị hàm hội quy và dữ liệu thực ')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Label3 = uilabel(app.HoiQuy);
            app.Label3.Text = 'Phương pháp';
            app.KtquphngtrnhhiquyLabel = uilabel(app.HoiQuy);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.EditFieldDaThucHoiQuy = uieditfield(app.HoiQuy, 'text');
            app.EditFieldDaThucHoiQuy.Editable = 'off';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.HoiQuy);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.EditFieldXi = uieditfield(app.HoiQuy, 'text');
            app.EditFieldXi.ValueChangedFcn = createCallbackFcn(app, @EditFieldXiValueChanged, true);
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.HoiQuy);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhâp dữ liệu y';
            app.EditFieldYi = uieditfield(app.HoiQuy, 'text');
            app.EditFieldYi.ValueChangedFcn = createCallbackFcn(app, @EditFieldYiValueChanged, true);
            app.ButtonTuyenTinh = uibutton(app.HoiQuy, 'push');
            app.ButtonTuyenTinh.ButtonPushedFcn = createCallbackFcn(app, @ButtonTuyenTinhPushed, true);
            app.ButtonTuyenTinh.Text = 'Tuyến tính';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HoiQuy);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.EditFieldGiaTriDuDoan = uieditfield(app.HoiQuy, 'text');
            app.EditFieldGiaTriDuDoan.ValueChangedFcn = createCallbackFcn(app, @EditFieldGiaTriDuDoanValueChanged, true);
            app.KtqudonEditFieldLabel = uilabel(app.HoiQuy);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.EditFieldKetQuaDuDoan = uieditfield(app.HoiQuy, 'text');
            app.EditFieldKetQuaDuDoan.Editable = 'off';
            app.ButtonMu = uibutton(app.HoiQuy, 'push');
            app.ButtonMu.ButtonPushedFcn = createCallbackFcn(app, @ButtonMuValueChanged, true);
            app.ButtonMu.Text = 'Mũ';
            app.DaoHam = uitab(app.TabGroup);
            app.DaoHam.Title = 'Đạo hàm';
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.DaoHam);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.EditFieldX = uieditfield(app.DaoHam, 'text');
            app.EditFieldX.ValueChangedFcn = createCallbackFcn(app, @EditFieldXValueChanged, true);
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.DaoHam);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.EditFieldY = uieditfield(app.DaoHam, 'text');
            app.EditFieldY.ValueChangedFcn = createCallbackFcn(app, @EditFieldYValueChanged, true);
            app.Label4 = uilabel(app.DaoHam);
            app.Label4.Text = 'hoặc';
            app.NhphmsEditFieldLabel = uilabel(app.DaoHam);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số ';
            app.EditFieldFunc = uieditfield(app.DaoHam, 'text');
            app.EditFieldFunc.ValueChangedFcn = createCallbackFcn(app, @EditFieldFuncValueChanged, true);
            app.Label6 = uilabel(app.DaoHam);
            app.Label6.Text = 'Phương pháp';
            app.ButtonMid = uibutton(app.DaoHam, 'push');
            app.ButtonMid.ButtonPushedFcn = createCallbackFcn(app, @ButtonMidPushed, true);
            app.ButtonMid.Text = 'Xấp xỉ trung tâm';
            app.ButtonBack = uibutton(app.DaoHam, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Text = 'Xấp xỉ lùi';
            app.EditFieldForward = uibutton(app.DaoHam, 'push');
            app.EditFieldForward.ButtonPushedFcn = createCallbackFcn(app, @EditFieldForwardButtonPushed, true);
            app.EditFieldForward.Text = 'Xấp xỉ tiến';
            app.KtquEditFieldLabel = uilabel(app.DaoHam);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.EditFieldKetQuaDaoHam = uieditfield(app.DaoHam, 'text');
            app.EditFieldKetQuaDaoHam.Editable = 'off';
            app.GitrsaisSwitchLabel = uilabel(app.DaoHam);
            app.GitrsaisSwitchLabel.Text = 'Giá trị sai số';
            app.SwitchGiatriSaiso = uiswitch(app.DaoHam, 'slider');
            app.SwitchGiatriSaiso.Items = {'O(h)', 'O(h^2)'};
            app.SwitchGiatriSaiso.ItemsData = {'O(h) is chosen', 'O(h^2) is chosen'};
            app.SwitchGiatriSaiso.ValueChangedFcn = createCallbackFcn(app, @SwitchGiatriSaisoValueChanged, true);
            app.SwitchGiatriSaiso.Value = 'O(h) is chosen';
            app.NhpbchEditFieldLabel_2 = uilabel(app.DaoHam);
            app.NhpbchEditFieldLabel_2.Text = 'Nhập bước h';
            app.EditFieldH = uieditfield(app.DaoHam, 'text');
            app.EditFieldH.ValueChangedFcn = createCallbackFcn(app, @EditFieldHValueChanged, true);
            app.NhpgitrcntnhohmEditFieldLabel_2 = uilabel(app.DaoHam);
            app.NhpgitrcntnhohmEditFieldLabel_2.Text = 'Nhập giá trị cần tính đạo hàm';
            app.EditFieldGiaTriDaoHam = uieditfield(app.DaoHam, 'text');
            app.EditFieldGiaTriDaoHam.ValueChangedFcn = createCallbackFcn(app, @EditFieldGiaTriDaoHamValueChanged, true);
            app.TichPhan = uitab(app.TabGroup);
            app.TichPhan.Title = 'Tích phân';
            app.NhpdliuxEditFieldLabel_4 = uilabel(app.TichPhan);
            app.NhpdliuxEditFieldLabel_4.Text = 'Nhập dữ liệu x';
            app.EditFieldXT = uieditfield(app.TichPhan, 'text');
            app.EditFieldXT.ValueChangedFcn = createCallbackFcn(app, @EditFieldXTValueChanged, true);
            app.NhpdliuyEditFieldLabel_4 = uilabel(app.TichPhan);
            app.NhpdliuyEditFieldLabel_4.Text = 'Nhập dữ liệu y';
            app.EditFieldYT = uieditfield(app.TichPhan, 'text');
            app.EditFieldYT.ValueChangedFcn = createCallbackFcn(app, @EditFieldYTValueChanged, true);
            app.Label7 = uilabel(app.TichPhan);
            app.Label7.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TichPhan);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.EditFieldFuncT = uieditfield(app.TichPhan, 'text');
            app.EditFieldFuncT.ValueChangedFcn = createCallbackFcn(app, @EditFieldFuncTValueChanged, true);
            app.NhpcntnhtchphnEditFieldLabel = uilabel(app.TichPhan);
            app.NhpcntnhtchphnEditFieldLabel.Text = 'Nhập cận để tính tích phân';
            app.EditFieldNhapCan = uieditfield(app.TichPhan, 'text');
            app.EditFieldNhapCan.ValueChangedFcn = createCallbackFcn(app, @EditFieldNhapCanValueChanged, true);
            app.KtquEditFieldLabel_2 = uilabel(app.TichPhan);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.EditFieldKetQuaTichPhan = uieditfield(app.TichPhan, 'text');
            app.EditFieldKetQuaTichPhan.Editable = 'off';
            app.NhpNEditFieldLabel = uilabel(app.TichPhan);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.EditFieldNhapN = uieditfield(app.TichPhan, 'text');
            app.EditFieldNhapN.ValueChangedFcn = createCallbackFcn(app, @EditFieldNhapNValueChanged, true);
            app.ButtonSimpson13 = uibutton(app.TichPhan, 'push');
            app.ButtonSimpson13.ButtonPushedFcn = createCallbackFcn(app, @ButtonSimpson13Pushed, true);
            app.ButtonSimpson13.Text = 'Simpson 1/3';
            app.ButtonSimpson38 = uibutton(app.TichPhan, 'push');
            app.ButtonSimpson38.ButtonPushedFcn = createCallbackFcn(app, @ButtonSimpson38Pushed, true);
            app.ButtonSimpson38.Text = 'Simpson 3/8';
            app.ButtonHinhThang = uibutton(app.TichPhan, 'push');
            app.ButtonHinhThang.ButtonPushedFcn = createCallbackFcn(app, @ButtonHinhThangPushed, true);
            app.ButtonHinhThang.Text = 'Hình thang';
            app.Label8 = uilabel(app.TichPhan);
            app.Label8.Text = 'Phương pháp';
            app.GioiThieuNhom = uitab(app.TabGroup);
            app.GioiThieuNhom.Title = 'Giới thiệu nhóm';
            app.SinhvinthchinTextAreaLabel = uilabel(app.GioiThieuNhom);
            app.SinhvinthchinTextAreaLabel.Text = 'Sinh viên thực hiện';
            app.SinhvinthchinTextArea = uitextarea(app.GioiThieuNhom);
            app.SinhvinthchinTextArea.Value = {'Đặng Xuân Nam - 22200106'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tonghop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end